classdef FieldDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        DrawingMenu                    matlab.ui.container.Menu
        CircleMenu                     matlab.ui.container.Menu
        RectangleMenu                  matlab.ui.container.Menu
        EllipseMenu                    matlab.ui.container.Menu
        PolygonMenu                    matlab.ui.container.Menu
        OptionsPanel                   matlab.ui.container.Panel
        BoundaryconditionsButtonGroup  matlab.ui.container.ButtonGroup
        PeriodicButton                 matlab.ui.control.RadioButton
        UnboundedButton                matlab.ui.control.RadioButton
        BoxButton                      matlab.ui.control.RadioButton
        FieldwidthSliderLabel          matlab.ui.control.Label
        FieldwidthSlider               matlab.ui.control.Slider
        FieldheightSliderLabel         matlab.ui.control.Label
        FieldheightSlider              matlab.ui.control.Slider
        UIAxes2                        matlab.ui.control.UIAxes
        AddButton                      matlab.ui.control.Button
        SubtractButton                 matlab.ui.control.Button
    end

    % (Global) data storage properties
    properties (Access = private)
        Height % Actual height of the field (in units of lambda)
        DesignHeight % Height of the image-based design
        Width
        DesignWidth
        BoundaryConds
        FieldDesign
        resUp % Number of boundary points that should be fitted into one unit of simulation space (in units of lambda) 
    end
    
    methods (Access = private)
        
        function [] = updateDesign(app,h,addState)
            %Updates the field design image to accommodate the new barrier shape contained in h.
            
            switch class(h)
                case 'images.roi.Polygon'
                    tmpField = poly2mask(h.Position(:,1)*app.resUp,h.Position(:,2)*app.resUp,size(app.FieldDesign,1),size(app.FieldDesign,2));
                case 'images.roi.Circle'
                    tmpField = poly2mask(h.Vertices(:,1)*app.resUp,h.Vertices(:,2)*app.resUp,size(app.FieldDesign,1),size(app.FieldDesign,2));
                case 'images.roi.Ellipse'
                    tmpField = poly2mask(h.Vertices(:,1)*app.resUp,h.Vertices(:,2)*app.resUp,size(app.FieldDesign,1),size(app.FieldDesign,2));
                case 'images.roi.Rectangle'
                    tmpField = poly2mask(h.Vertices(:,1)*app.resUp,h.Vertices(:,2)*app.resUp,size(app.FieldDesign,1),size(app.FieldDesign,2));
            end
            
            origField = app.FieldDesign;
            if addState
                newField = or(tmpField,origField);
            elseif ~addState
                newField = origField-tmpField;
                newField(newField < 0) = 0;
            end
            
            app.FieldDesign = newField;
        end
    end
 

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Initialise variables
            app.resUp = 2;
            app.DesignHeight = (floor(app.FieldheightSlider.Value/2)*2*app.resUp)+1;
            app.Height = app.DesignHeight/app.resUp;
            app.DesignWidth = (floor(app.FieldwidthSlider.Value/2)*2*app.resUp)+1;
            app.Width = app.DesignWidth/app.resUp;
            app.FieldDesign = zeros(app.DesignHeight,app.DesignWidth); % Upsampled image consisting of the desired boundary
            
            app.AddButton.UserData = false;
            app.SubtractButton.UserData = false;
            
            if app.PeriodicButton.Value
                app.BoundaryConds = 'periodic';
            else
                app.BoundaryConds = 'none';
            end
            
            cla(app.UIAxes2);
            imshow(app.FieldDesign,'Parent',app.UIAxes2);
            app.UIAxes2.YDir = 'normal';
        end

        % Value changed function: FieldwidthSlider
        function FieldwidthSliderValueChanged(app, event)
            app.DesignWidth = (floor(app.FieldwidthSlider.Value/2)*2*app.resUp)+1;
            app.Width = app.DesignWidth/app.resUp;
            app.FieldDesign = zeros(app.DesignHeight,app.DesignWidth);
            
            cla(app.UIAxes2);
            imshow(app.FieldDesign,'Parent',app.UIAxes2)
            app.UIAxes2.YDir = 'normal';
            
            app.FieldwidthSlider.Value = app.Width;
        end

        % Value changed function: FieldheightSlider
        function FieldheightSliderValueChanged(app, event)
            app.DesignHeight = (floor(app.FieldheightSlider.Value/2)*2*app.resUp)+1;
            app.Height = app.DesignHeight/app.resUp;
            app.FieldDesign = zeros(app.DesignHeight,app.DesignWidth);
            
            cla(app.UIAxes2);
            imshow(app.FieldDesign,'Parent',app.UIAxes2);
            app.UIAxes2.YDir = 'normal';
            
            app.FieldheightSlider.Value = app.Height;
        end

        % Selection changed function: BoundaryconditionsButtonGroup
        function BoundaryconditionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.BoundaryconditionsButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Periodic'
                    app.BoundaryConds = 'periodic';
                case 'Unbounded'
                    app.BoundaryConds = 'none';
                case 'Box'
                    app.BoundaryConds = 'box';
            end
        end

        % Menu selected function: CircleMenu
        function CircleMenuSelected(app, event)
             % Create axes
            ax = axes(app.UIFigure,'Box','on');
            ax.Position = [0.4,0.4,0.5,0.55];
            
            imagesc(ax,[0,app.Width],[0,app.Height],app.FieldDesign)
            
            axis(ax,'equal');
            axis(ax,'tight');
            ax.YDir = 'normal';
                        
            if strcmp(app.BoundaryConds,'periodic')
                line(ax,[0,app.Width],[0,0],'Color','r')
                line(ax,[0,app.Width],[app.Height,app.Height],'Color','r')
                line(ax,[0,0],[0,app.Height],'Color','r')
                line(ax,[app.Width,app.Width],[0,app.Height],'Color','r')
                
                ax.XLim = [-app.Width/20,app.Width*21/20];
                ax.YLim = [-app.Height/20,app.Height*21/20];
            else
                ax.XLim = [0,app.Width];
                ax.YLim = [0,app.Height];
            end
            
            h = drawcircle(ax,'Center',[app.Width/2,app.Height/2],'Radius',min(app.Width,app.Height)/4);
            
            %Loops until one of the two buttons is pressed
            while true
                pause(0.05)
                addState = get(app.AddButton, 'UserData');
                subtractState = get(app.SubtractButton, 'UserData');
                if or(addState,subtractState)
                    app.AddButton.UserData = false;
                    app.SubtractButton.UserData = false;
                    break;
                end
            end
            
            app.updateDesign(h,addState)
            
            delete(ax)
            
            cla(app.UIAxes2);
            imshow(app.FieldDesign,'Parent',app.UIAxes2);
            app.UIAxes2.YDir = 'normal';
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [file,path] = uiputfile({'*.mat'});
            
            %Make a structure in which the configuration data is stored
            fieldConfig = struct;
            fieldConfig.Width = size(app.FieldDesign,2)/app.resUp;
            fieldConfig.Height = size(app.FieldDesign,1)/app.resUp;
            fieldConfig.FieldDesign = app.FieldDesign;
            fieldConfig.BoundaryConds = app.BoundaryConds;
            fieldConfig.resUp = app.resUp;
            
            %Get boundary points (where the actual confinement points will be placed)
            outer = bwperim(app.FieldDesign);
            switch app.BoundaryConds
                case 'box'
                    %Include all points around the boundary
                    outer(1,:) = 1;
                    outer(end,:) = 1;
                    outer(:,1) = 1;
                    outer(:,end) = 1;
                case 'none'
                    %Remove all  points around the boundary
                    outer(1,:) = 0;
                    outer(end,:) = 0;
                    outer(:,1) = 0;
                    outer(:,end) = 0;
                case 'periodic'
                    %Remove all  points around the boundary
                    outer(1,:) = 0;
                    outer(end,:) = 0;
                    outer(:,1) = 0;
                    outer(:,end) = 0;
                    
                    %Need to restore little 'spurs' wherever a line almost touches the edge of the image
                    outer(1,outer(2,:)==1) = 1;
                    outer(end,outer(end-1,:)==1) = 1;
                    outer(outer(:,2)==1,1) = 1;
                    outer(outer(:,end-1)==1,end) = 1;
            end
            [tmpY,tmpX] = find(outer);
            
            fieldConfig.CPs = [tmpX-0.5,tmpY-0.5]/app.resUp;
            
            save([path,file],'fieldConfig')
        end

        % Menu selected function: RectangleMenu
        function RectangleMenuSelected(app, event)
            % Create axes
            ax = axes(app.UIFigure,'Box','on');
            ax.Position = [0.4,0.4,0.5,0.55];
            
            imagesc(ax,[0,app.Width],[0,app.Height],app.FieldDesign)
            
            axis(ax,'equal');
            axis(ax,'tight');
            ax.YDir = 'normal';
                        
            if strcmp(app.BoundaryConds,'periodic')
                line(ax,[0,app.Width],[0,0],'Color','r')
                line(ax,[0,app.Width],[app.Height,app.Height],'Color','r')
                line(ax,[0,0],[0,app.Height],'Color','r')
                line(ax,[app.Width,app.Width],[0,app.Height],'Color','r')
                
                ax.XLim = [-app.Width/20,app.Width*21/20];
                ax.YLim = [-app.Height/20,app.Height*21/20];
            else
                ax.XLim = [0,app.Width];
                ax.YLim = [0,app.Height];
            end
            
            h = drawrectangle(ax,'Position',[app.Width/4,app.Height/4,app.Width/2,app.Height/2]);
            
            %Loops until one of the two buttons is pressed
            while true
                pause(0.05)
                addState = get(app.AddButton, 'UserData');
                subtractState = get(app.SubtractButton, 'UserData');
                if or(addState,subtractState)
                    app.AddButton.UserData = false;
                    app.SubtractButton.UserData = false;
                    break;
                end
            end
            
            app.updateDesign(h,addState)
            
            delete(ax)
            
            cla(app.UIAxes2);
            imshow(app.FieldDesign,'Parent',app.UIAxes2);
            app.UIAxes2.YDir = 'normal';
        end

        % Menu selected function: EllipseMenu
        function EllipseMenuSelected(app, event)
             % Create axes
            ax = axes(app.UIFigure,'Box','on');
            ax.Position = [0.4,0.4,0.5,0.55];
            
            imagesc(ax,[0,app.Width],[0,app.Height],app.FieldDesign)
            
            axis(ax,'equal');
            axis(ax,'tight');
            ax.YDir = 'normal';
            
            if strcmp(app.BoundaryConds,'periodic')                
                line(ax,[0,app.Width],[0,0],'Color','r')
                line(ax,[0,app.Width],[app.Height,app.Height],'Color','r')
                line(ax,[0,0],[0,app.Height],'Color','r')
                line(ax,[app.Width,app.Width],[0,app.Height],'Color','r')
                
                ax.XLim = [-app.Width/20,app.Width*21/20];
                ax.YLim = [-app.Height/20,app.Height*21/20];
            else
                ax.XLim = [0,app.Width];
                ax.YLim = [0,app.Height];
            end
            
            h = drawellipse(ax,'Center',[app.Width/2,app.Height/2],'SemiAxes',[app.Width/4,app.Height/4]);
            
            %Loops until one of the two buttons is pressed
            while true
                pause(0.05)
                addState = get(app.AddButton, 'UserData');
                subtractState = get(app.SubtractButton, 'UserData');
                if or(addState,subtractState)
                    app.AddButton.UserData = false;
                    app.SubtractButton.UserData = false;
                    break;
                end
            end
            
            app.updateDesign(h,addState)
            
            delete(ax)
            
            cla(app.UIAxes2);
            imshow(app.FieldDesign,'Parent',app.UIAxes2);
            app.UIAxes2.YDir = 'normal';
        end

        % Menu selected function: PolygonMenu
        function PolygonMenuSelected(app, event)
             % Create axes
            ax = axes(app.UIFigure,'Box','on');
            ax.Position = [0.4,0.4,0.5,0.55];
            
            imagesc(ax,[0,app.Width],[0,app.Height],app.FieldDesign)
            
            axis(ax,'equal');
            axis(ax,'tight');
            ax.YDir = 'normal';
            
            if strcmp(app.BoundaryConds,'periodic')                
                line(ax,[0,app.Width],[0,0],'Color','r')
                line(ax,[0,app.Width],[app.Height,app.Height],'Color','r')
                line(ax,[0,0],[0,app.Height],'Color','r')
                line(ax,[app.Width,app.Width],[0,app.Height],'Color','r')
                
                ax.XLim = [-app.Width/20,app.Width*21/20];
                ax.YLim = [-app.Height/20,app.Height*21/20];
            else
                ax.XLim = [0,app.Width];
                ax.YLim = [0,app.Height];
            end
            
            %Create a regular polygon with a user-defined number of points
            response = inputdlg('How many corners for polygon?','Point of order',1);
            cornNo = str2num(response{1});
            
            Radius = min(app.Height,app.Width)/2;
            Centre = [app.Width,app.Height]/2;
            
            angs = linspace(0,2*pi-(2*pi/cornNo),cornNo);
            Vertices = [Centre(1) + Radius*sin(angs'),Centre(2) + Radius*cos(angs')];
            
            h = drawpolygon(ax,'Position',Vertices);
            
            %Loops until one of the two buttons is pressed
            while true
                pause(0.05)
                addState = get(app.AddButton, 'UserData');
                subtractState = get(app.SubtractButton, 'UserData');
                if or(addState,subtractState)
                    app.AddButton.UserData = false;
                    app.SubtractButton.UserData = false;
                    break;
                end
            end
            
            app.updateDesign(h,addState)
            
            delete(ax)
            
            cla(app.UIAxes2);
            imshow(app.FieldDesign,'Parent',app.UIAxes2);
            app.UIAxes2.YDir = 'normal';
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.AddButton.UserData = true;
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            app.SubtractButton.UserData = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.DrawingMenu = uimenu(app.UIFigure);
            app.DrawingMenu.Text = 'Drawing';
            app.CircleMenu = uimenu(app.DrawingMenu);
            app.CircleMenu.MenuSelectedFcn = createCallbackFcn(app, @CircleMenuSelected, true);
            app.CircleMenu.Text = 'Circle';
            app.RectangleMenu = uimenu(app.DrawingMenu);
            app.RectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @RectangleMenuSelected, true);
            app.RectangleMenu.Text = 'Rectangle';
            app.EllipseMenu = uimenu(app.DrawingMenu);
            app.EllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @EllipseMenuSelected, true);
            app.EllipseMenu.Text = 'Ellipse';
            app.PolygonMenu = uimenu(app.DrawingMenu);
            app.PolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @PolygonMenuSelected, true);
            app.PolygonMenu.Text = 'Polygon';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.BoundaryconditionsButtonGroup = uibuttongroup(app.OptionsPanel);
            app.BoundaryconditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundaryconditionsButtonGroupSelectionChanged, true);
            app.BoundaryconditionsButtonGroup.Title = 'Boundary conditions';
            app.PeriodicButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.PeriodicButton.Text = 'Periodic';
            app.PeriodicButton.Value = true;
            app.UnboundedButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.UnboundedButton.Text = 'Unbounded';
            app.BoxButton = uiradiobutton(app.BoundaryconditionsButtonGroup);
            app.BoxButton.Text = 'Box';
            app.FieldwidthSliderLabel = uilabel(app.OptionsPanel);
            app.FieldwidthSliderLabel.Text = 'Field width';
            app.FieldwidthSlider = uislider(app.OptionsPanel);
            app.FieldwidthSlider.Limits = [20 200];
            app.FieldwidthSlider.MajorTicks = [20 60 100 140 180];
            app.FieldwidthSlider.ValueChangedFcn = createCallbackFcn(app, @FieldwidthSliderValueChanged, true);
            app.FieldwidthSlider.Value = 20;
            app.FieldheightSliderLabel = uilabel(app.OptionsPanel);
            app.FieldheightSliderLabel.Text = 'Field height';
            app.FieldheightSlider = uislider(app.OptionsPanel);
            app.FieldheightSlider.Limits = [20 200];
            app.FieldheightSlider.MajorTicks = [20 60 100 140 180];
            app.FieldheightSlider.ValueChangedFcn = createCallbackFcn(app, @FieldheightSliderValueChanged, true);
            app.FieldheightSlider.Value = 20;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Design')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SubtractButton = uibutton(app.UIFigure, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FieldDesigner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end